classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AnalyzemusicButton          matlab.ui.control.Button
        indic                       matlab.ui.control.NumericEditField
        VocalIndicesEditFieldLabel  matlab.ui.control.Label
        ampli                       matlab.ui.control.NumericEditField
        VocalAmplitudesLabel        matlab.ui.control.Label
        NoiseremovalButton          matlab.ui.control.Button
        VoiceRecordingButton        matlab.ui.control.Button
        EchoButton                  matlab.ui.control.Button
        ReverseButton               matlab.ui.control.Button
        StopButton_2                matlab.ui.control.Button
        FunnyButton                 matlab.ui.control.Button
        ChangevoicefrequencyLabel   matlab.ui.control.Label
        freq                        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel     matlab.ui.control.Label
        gender                      matlab.ui.control.EditField
        GenderLabel                 matlab.ui.control.Label
        file_name                   matlab.ui.control.EditField
        FileNameEditFieldLabel      matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        ListenAudioButton           matlab.ui.control.Button
        GenerateGraphButton         matlab.ui.control.Button
        SourceButton                matlab.ui.control.Button
        file_msg                    matlab.ui.control.TextArea
        CalculateButton             matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        file_ % Variabila pentru stocarea numelui fișierului
        flag=0; %Variabila pentru un anumit comportament, inițializată cu 0
        path %Variabila pentru stocarea căii fișierului
        aud_file %Variabila pentru stocarea datelor audio
        aud_fs=0; %Frecvența de eșantionare a datelor audio, inițializată cu 0
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: ListenAudioButton
        function ListenAudioButtonPushed(app, event)
            sound(app.aud_file,app.aud_fs);
        end

        % Button pushed function: SourceButton
        function SourceButtonPushed(app, event)
            [file, p] = uigetfile({'*.mp3;*.wav', 'Audio Files (*.mp3, *.wav)'}, 'Select an audio file');

            if ischar(file) && (endsWith(file, '.mp3') || endsWith(file, '.wav'))
                app.flag = 1;
                app.file_msg.Value = 'File Uploaded!!';
                app.path = p;
                s = fullfile(p, file);
                app.file_name.Value = file;
                app.file_ = s;
                app.flag = 1;
            
                % Read the audio file based on the file extension
                if endsWith(file, '.mp3')
                    [app.aud_file, app.aud_fs] = audioread(app.file_, 'native');
                elseif endsWith(file, '.wav')
                    [app.aud_file, app.aud_fs] = audioread(app.file_);
                end
            else
                % Display an error message if an incorrect file is selected
                app.file_msg.Value = 'Incorrect File Format. Please select a .mp3 or .wav file.';
            end
 
        end

        % Button pushed function: GenerateGraphButton
        function GenerateGraphButtonPushed(app, event)
            plot(app.UIAxes,app.aud_file);
            title(app.UIAxes,"Audio");
            xlabel(app.UIAxes,"Time")
            ylabel(app.UIAxes,"Amplitude")
            grid(app.UIAxes,"on");
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          % Verifică dacă flagul este setat
            if app.flag == 1
                try
                    % Citeste fișierul audio și extrage datele și frecvența de eșantionare
                    [y, Fs] = audioread(app.file_);
                    % Setează dimensiunea unei ferestre pentru analiza semnalului
                    frame = 1024;
                    
                    % Salvează datele audio și frecvența de eșantionare în proprietățile aplicației
                    app.aud_file = y;
                    app.aud_fs = Fs;
                    
                    % Aplică un filtru Butterworth pentru a evidenția componentele de frecvență
                    [b0, a0] = butter(4, [50/(Fs/2), 500/(Fs/2)]);
                    
                    % Calculul frecvenței fundamentale folosind autocorelarea
                    F0 = zeros(1, floor(length(y)/frame));
                    for i = 1:length(y)/frame
                        x = y(1+(i-1)*frame:i*frame);
                        xin = abs(x);
                        xin = filter(b0, a0, xin);
                        xin = xin - mean(xin);
                        
                        % Algoritm de autocorelare pentru a calcula frecvența fundamentală
                        R = xcorr(xin);
                        [~, loc] = findpeaks(R(frame:end), 'NPeaks', 1, 'MinPeakHeight', 0.1);
                        
                        % Asigură-te că loc(1) nu depășește lungimea vectorului R
                        if ~isempty(loc) && loc(1) <= length(R)
                            F0(i) = Fs / max(round(loc(1)), 1); % Evită împărțirea la zero
                        else
                            F0(i) = 0; % Poți trata acest caz într-un mod corespunzător
                        end 
                        disp(['Frame ' num2str(i) ', F0: ' num2str(F0(i))]);
                    end
                    
                    % Afișează frecvența medie estimată pe GUI
                    app.freq.Value = mean(F0);
                    % Verifică dacă frecvența medie indică o voce masculină sau feminină
                    if app.freq.Value > 165 
                        app.gender.Value = 'Female';
                        y  = y / max(abs(y));
                        spectrum = abs(fft(y));
                        vocalRange = [80, 300];
                        vocalIndices = find(Fs/ 2 * linspace(0, 1, length(spectrum)/2 + 1) >= vocalRange(1) & Fs / 2 * linspace(0, 1, length(spectrum)/2 + 1) <= vocalRange(2));
                        vocalAmplitudes = spectrum(vocalIndices);
                        app.ampli.Value = mean(vocalAmplitudes);
                        app.indic.Value = mean(vocalIndices);
                        app.file_msg.Value = 'Done';
                    elseif app.freq.Value > 85 && app.freq.Value < 165
                        app.gender.Value = 'Male';
                        app.file_msg.Value = 'Done';
                    end
                    
                catch
                    % În cazul unei excepții, afișează un mesaj de eroare pe GUI
                    app.file_msg.Value = 'Error processing file. Please check the format.';
                end
            else
                % Dacă flagul nu este setat, afișează un mesaj de eroare pe GUI
                app.file_msg.Value = 'Incorrect Format!!';
            end

        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            clear sound;
        end

        % Button pushed function: FunnyButton
        function FunnyButtonPushed(app, event)
            
            % Ajustarea frecventei fundamentale pentru a face vocea pitigaiata
            pitchFactor = 1.5; % Mărește frecvența fundamentală pentru a face vocea pitigaiata
            outputAudio = interp1(1:length(app.aud_file), app.aud_file, linspace(1, length(app.aud_file), round(length(app.aud_file) / pitchFactor)), 'spline');
        
            % Afișarea sunetului după ajustarea tonalității pe UIAxes_2
            plot(app.UIAxes_2, outputAudio);
            title(app.UIAxes_2, 'Sunetul cu Voce Pitigaiata');
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude');
            grid(app.UIAxes_2,"on");
           
            % Redare sunet cu voce pitigaiata
            sound(outputAudio, app.aud_fs);
        end

        % Callback function
        function ArmonizareButtonPushed(app, event)
            % Frecvența fundamentală a vocii principale
            f0_principal = 440;
            % Frecvența armoniei (de exemplu, o terță majoră deasupra vocii principale)
            f0_armonie = f0_principal * 5/4;  % Ajustează pentru intervalul de terță majoră
            
            % Durata în eșantioane a segmentului vocal
            durata = length(app.aud_file);
            
            % Generează armonia
            armonie = 0.5 * sin(2 * pi * f0_armonie * (0:durata-1) / app.aud_fs);
            
            % Adaugă armonia la vocea principală
            outputAudio = app.aud_file + armonie';

            % Afișarea sunetului după ajustarea tonalității pe UIAxes_2
            plot(app.UIAxes_2, outputAudio);
            title(app.UIAxes_2, 'Sunetul cu Voce Pitigaiata');
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude');
            grid(app.UIAxes_2,"on");
            % Redare sunet cu voce pitigaiata
            sound(outputAudio, app.aud_fs);
        end

        % Callback function
        function StopButton_3Pushed(app, event)
            clear sound;
        end

        % Button pushed function: ReverseButton
        function ReverseButtonPushed(app, event)
            outputAudio = flipud(app.aud_file);
            
            plot(app.UIAxes_2, outputAudio);
            title(app.UIAxes_2, 'Sunetul cu Reverse');
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude')
            grid(app.UIAxes_2,"on");
            % Redare sunet inversat
            sound(outputAudio, app.aud_fs);
        end

        % Callback function
        function StopButton_4Pushed(app, event)
            clear sound;
        end

        % Button pushed function: EchoButton
        function EchoButtonPushed(app, event)

            % Crearea unui impuls de răspuns la impuls pentru ecou (exemplu simplificat)
            echo_IR = zeros(1, round(app.aud_fs * 0.5));  % Impulsul de răspuns la impuls are o durată de 0.5 secunde
            echo_IR(1) = 1;  % Impuls la început
            
            % Ajustarea factorului de atenuare pentru ecou
            attenuationFactor = 0.5;
            
            % Crearea unui semnal cu ecou prin convoluție
            echoSignal = conv(app.aud_file(:), echo_IR, 'same') * attenuationFactor;
            
            % Adăugarea semnalului cu ecou la sunetul original
            outputAudio = app.aud_file(:) + echoSignal;
            
            % Normalizarea amplitudinii pentru a evita clipping-ul
            outputAudio = outputAudio / max(abs(outputAudio));
            
            % Afișarea sunetului după ajustarea tonalității pe UIAxes_2
            plot(app.UIAxes_2, outputAudio);
            title(app.UIAxes_2, 'Sunetul cu Ecou');
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude');
            grid(app.UIAxes_2,"on");
            % Redare sunet cu efect de ecou adăugat

            sound(outputAudio, app.aud_fs);
        end

        % Callback function
        function StopButton_5Pushed(app, event)
            clear sound;
        end

        % Callback function
        function FiltrutrecesusButtonPushed(app, event)
            cutoffFrequency = 1000;  % Frecvența de tăiere în Hz
            filterOrder = 4;   
            % Crearea filtrului trece-sus
            b = fir1(filterOrder, cutoffFrequency / (app.aud_fs / 2), 'high');
            
            % Aplicarea filtrului trece-sus
            outputAudio = filter(b, 1, app.aud_file);
            
            % Redare sunet dupa aplicarea filtrului trece-sus
            sound(outputAudio, app.aud_fs);

        end

        % Callback function
        function StopButton_6Pushed(app, event)
            clear sound;
        end

        % Button pushed function: VoiceRecordingButton
        function VoiceRecordingButtonPushed(app, event)
            i=0;
            %recorder object
            recorder = audiorecorder(44100,24, 2);
            %record 5 sec
            disp('Record your voice 5 sec');
            drawnow();
            pause(1);
            recordblocking(recorder, 5);
            play(recorder)
            data = getaudiodata(recorder);
            name = 'record';
            form = '.wav';
            i=i+1;
            outputPath= sprintf('%s_%d%s', name, i, form);
            audiowrite(outputPath,data,44100);
            f=voiceFFT(data);
            function [x] = voiceFFT(data)
            F=fft(data(:,1));
            n=max(real(F));
            x=find(real(F)==n,1)
            end
            file = uigetfile(outputPath);

            if ischar(file) && (endsWith(file, '.mp3') || endsWith(file, '.wav'))
                app.flag = 1;
                app.file_msg.Value = 'File Uploaded!!';
                s = fullfile(file);
                app.file_name.Value = file;
                app.file_ = s;
            
                % Read the audio file based on the file extension
                if endsWith(file, '.mp3')
                    [app.aud_file, app.aud_fs] = audioread(app.file_, 'native');
                elseif endsWith(file, '.wav')
                    [app.aud_file, app.aud_fs] = audioread(app.file_);
                end
            else
                % Display an error message if an incorrect file is selected
                app.file_msg.Value = 'Incorrect File Format. Please select a .mp3 or .wav file.';
            end
 
        end

        % Button pushed function: NoiseremovalButton
        function NoiseremovalButtonPushed(app, event)
            j=0;
            fc = 2500;  % Cut-off frequency in Hz
            order = 4;  % Filter order
            [b, a] = butter(order, fc/(app.aud_fs/2), 'low');  % Design the filter
            outputAudio = filter(b, a, app.aud_file);  % Apply the filter
            plot(app.UIAxes_2, outputAudio);
            title(app.UIAxes_2, 'Sunetul fara zgomot');
            xlabel(app.UIAxes_2, 'Time');
            ylabel(app.UIAxes_2, 'Amplitude');
            grid(app.UIAxes_2,"on");
            sound(outputAudio, app.aud_fs);
            name = 'filtarezgomot';
            form = '.wav';
            j=j+1;
            outputPath= sprintf('%s_%d%s', name, j, form);
            audiowrite(outputPath,outputAudio,44100);            
            file = uigetfile(outputPath);
            if ischar(file) && (endsWith(file, '.mp3') || endsWith(file, '.wav'))
                app.flag = 1;
                app.file_msg.Value = 'File Uploaded!!';
                s = fullfile(file);
                app.file_name.Value = file;
                app.file_ = s;
                app.flag = 1;
            
                % Read the audio file based on the file extension
                if endsWith(file, '.mp3')
                    [app.aud_file, app.aud_fs] = audioread(app.file_, 'native');
                elseif endsWith(file, '.wav')
                    [app.aud_file, app.aud_fs] = audioread(app.file_);
                end
            else
                % Display an error message if an incorrect file is selected
                app.file_msg.Value = 'Incorrect File Format. Please select a .mp3 or .wav file.';
            end
        end

        % Button pushed function: AnalyzemusicButton
        function AnalyzemusicButtonPushed(app, event)
            sound(app.aud_file,app.aud_fs);
            song = app.aud_file;
            Fs = app.aud_fs*4;   %Marirea freceventei
            t1 = 1;      % Setează limita inferioară
            t2 = 1e6;    % Setează limita superioară
            
            % Verificare lungime
            if t2 > length(song)
                t2 = length(song);
            end
            %Analizarea unei parti din cantec
            y = song(t1:t2);
            [~,n] = size(y);
            t = linspace(t1,t2,n);
            % Subesantionare cu m
            m = 20;
            Fsm = round(Fs/m);
            p = floor(n/m);
            y_avg = zeros(1,p);
            for i = 1:p
                y_avg(i) = mean(y(m*(i-1)+1:m*i));
            end
            
            % Pragul pentru a gasi notele
            close all
            y_thresh = zeros(1,p);
            i = 1;
            while (i <= p)
                thresh = 5*median(abs(y_avg(max(1,i-5000):i)));
                if (abs(y_avg(i)) > thresh)
                    for j = 0:500
                        if (i + j <= p)
                            y_thresh(i) = y_avg(i);
                            i = i + 1;
                        end
                    end
                    i = i + 1400;
                end
                i = i + 1;
            end
            % Frecventa fiecarei note
            i = 1;
            i_note = 0;
            while i < p
                j = 1;
                end_note = 0;
                while (((y_thresh(i) ~= 0) || (end_note > 0)) && (i < p))
                    note(j) = y_thresh(i);
                    i = i + 1;
                    j = j + 1;
                    if (y_thresh(i) ~= 0)
                        end_note = 20;
                    else
                        end_note = end_note - 1;
                    end
                    if (end_note == 0)
                       if (j > 25)
                           note_padded = [note zeros(1,j)];
                           Note = fft(note_padded);
                           Ns = length(note);
                           f = linspace(0,(1+Ns/2),Ns);
                           [~,index] = max(abs(Note(1:length(f))));
                           if (f(index) > 20)
                               i_note = i_note + 1;
                               fundamentals(i_note) = f(index)*2;
                           end
                           i = i + 50;
                       end
                       clear note;
                       break
                    end
                    
                end
                i = i + 1;
            end

        % play back
        amp = 1;
        fs = 20500;
        duration = .5;
        recreate_song = zeros(1,duration*fs*length(fundamentals));
        for i = 1:length(fundamentals)
            [letter(i,1),freq(i)]= FreqToNote(fundamentals(i));
            values = 0:1/fs:duration;
            a = amp*sin(2*pi*freq(i)*values*2);
            recreate_song((i-1)*fs*duration+1:i*fs*duration+1) = a;
        end
        sound(recreate_song,fs);
        function [note,frequency] = FreqToNote(f)
            index = round(17.31232*log(f) - 47.37620);
            frequencies = [16.35 17.32 18.35 19.45 20.6 21.83 23.12 24.5 25.96 27.5 29.14 30.87 32.7 34.65 36.71 38.89 41.2 43.65 46.25 49 51.91 55 58.27 61.74 65.41 69.3 73.42 77.78 82.41 87.31 92.5 98 103.83 110 116.54 123.47 130.81 138.59 146.83 155.56 164.81 174.61 185 196 207.65 220 233.08 246.94 261.63 277.18 293.66 311.13 329.63 349.23 369.99 392 415.3 440 466.16 493.88 523.25 554.37 587.33 622.25 659.25 698.46 739.99 783.99 830.61 880 932.33 987.77 1046.5 1108.73 1174.66 1244.51 1318.51 1396.91 1479.98 1567.98 1661.22 1760 1864.66 1975.53 2093 2217.46 2349.32 2489.02 2637.02 2793.83 2959.96 3135.96 3322.44 3520 3729.31 3951.07 4186.01 4434.92 4698.63 4978.03 5274.04 5587.65 5919.91 6271.93 6644.88 7040 7458.62 7902.13];
            notes = ["C0" "Db0" "D0" "Eb0" "E0" "F0" "Gb0" "G0" "Ab0" "A0" "Bb0" "B0" "C1" "Db1" "D1" "Eb1" "E1" "F1" "Gb1" "G1" "Ab1" "A1" "Bb1" "B1" "C2" "Db2" "D2" "Eb2" "E2" "F2" "Gb2" "G2" "Ab2" "A2" "Bb2" "B2" "C3" "Db3" "D3" "Eb3" "E3" "F3" "Gb3" "G3" "Ab3" "A3" "Bb3" "B3" "C4" "Db4" "D4" "Eb4" "E4" "F4" "Gb4" "G4" "Ab4" "A4" "Bb4" "B4" "C5" "Db5" "D5" "Eb5" "E5" "F5" "Gb5" "G5" "Ab5" "A5" "Bb5" "B5" "C6" "Db6" "D6" "Eb6" "E6" "F6" "Gb6" "G6" "Ab6" "A6" "Bb6" "B6" "C7" "Db7" "D7" "Eb7" "E7" "F7" "Gb7" "G7" "Ab7" "A7" "Bb7" "B7" "C8" "Db8" "D8" "Eb8" "E8" "F8" "Gb8" "G8" "Ab8" "A8" "Bb8" "B8"];
            note = notes(index);
            frequency = frequencies(index);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.file_msg = uitextarea(app.UIFigure);
            app.file_msg.Editable = 'off';
            app.file_msg.Value = {'Upload the File'};
            app.SourceButton = uibutton(app.UIFigure, 'push');
            app.SourceButton.ButtonPushedFcn = createCallbackFcn(app, @SourceButtonPushed, true);
            app.SourceButton.Text = 'Source';
            app.GenerateGraphButton = uibutton(app.UIFigure, 'push');
            app.GenerateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButtonPushed, true);
            app.GenerateGraphButton.Text = 'Generate Graph';
            app.ListenAudioButton = uibutton(app.UIFigure, 'push');
            app.ListenAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ListenAudioButtonPushed, true);
            app.ListenAudioButton.Text = 'Listen Audio';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.file_name = uieditfield(app.UIFigure, 'text');
            app.GenderLabel = uilabel(app.UIFigure);
            app.GenderLabel.Text = 'Gender';
            app.gender = uieditfield(app.UIFigure, 'text');
            app.gender.Editable = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.freq = uieditfield(app.UIFigure, 'numeric');
            app.freq.Editable = 'off';
            app.ChangevoicefrequencyLabel = uilabel(app.UIFigure);
            app.ChangevoicefrequencyLabel.Text = 'Change voice/frequency';
            app.FunnyButton = uibutton(app.UIFigure, 'push');
            app.FunnyButton.ButtonPushedFcn = createCallbackFcn(app, @FunnyButtonPushed, true);
            app.FunnyButton.Text = 'Funny';
            app.StopButton_2 = uibutton(app.UIFigure, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.ReverseButton = uibutton(app.UIFigure, 'push');
            app.ReverseButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseButtonPushed, true);
            app.ReverseButton.Text = 'Reverse';
            app.EchoButton = uibutton(app.UIFigure, 'push');
            app.EchoButton.ButtonPushedFcn = createCallbackFcn(app, @EchoButtonPushed, true);
            app.EchoButton.Text = 'Echo';
            app.VoiceRecordingButton = uibutton(app.UIFigure, 'push');
            app.VoiceRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @VoiceRecordingButtonPushed, true);
            app.VoiceRecordingButton.Text = 'Voice Recording';
            app.NoiseremovalButton = uibutton(app.UIFigure, 'push');
            app.NoiseremovalButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseremovalButtonPushed, true);
            app.NoiseremovalButton.Text = 'Noise removal';
            app.VocalAmplitudesLabel = uilabel(app.UIFigure);
            app.VocalAmplitudesLabel.Text = 'Vocal Amplitudes';
            app.ampli = uieditfield(app.UIFigure, 'numeric');
            app.ampli.Editable = 'off';
            app.VocalIndicesEditFieldLabel = uilabel(app.UIFigure);
            app.VocalIndicesEditFieldLabel.Text = 'Vocal Indices';
            app.indic = uieditfield(app.UIFigure, 'numeric');
            app.AnalyzemusicButton = uibutton(app.UIFigure, 'push');
            app.AnalyzemusicButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzemusicButtonPushed, true);
            app.AnalyzemusicButton.Text = 'Analyze music';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end